classdef ActitudTrayectoria < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        HoraNMLabel                matlab.ui.control.Label
        HoraNMEditField            matlab.ui.control.EditField
        LongitudLabel              matlab.ui.control.Label
        LongitudEditField          matlab.ui.control.EditField
        LatitudLabel               matlab.ui.control.Label
        LatitudEditField           matlab.ui.control.EditField
        AltitudLabel               matlab.ui.control.Label
        AltitudEditField           matlab.ui.control.EditField
        EtapaLabel                 matlab.ui.control.Label
        EtapaEditField             matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        mLabel                     matlab.ui.control.Label
        OrientaciondegrficasLabel  matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
load("Datosv1.mat")
pause(10)
ti = 129900;
tf = 306600; % 129900, 217800, 270900, 299400,306300, 320400;

ti2 = ti/300;

ldl = 0.5;
plot3(app.UIAxes3,[-ldl ldl],[0 0],[0 0],'b','LineWidth',3)
hold(app.UIAxes3,'on')
app.UIAxes3.View = [-156,10];
plot3(app.UIAxes3,[0 0],[-ldl ldl],[0 0],'r','LineWidth',3)
NSEO = {'E';'O';'N';'S'};
%text(app.ax, x, y, tx, 'VerticalAlignment','bottom') 
text(app.UIAxes3,[ldl -ldl 0 0],[0 0 ldl -ldl],[0 0 0 0],NSEO,'FontSize',10,'VerticalAlignment','top')

pause(1)

CCx1 = [0.5   0.5  0.5   0.5   0.5;
      -0.5  -0.5  0.5   0.5  -0.5;
      -0.5   0.5  0.5  -0.5  -0.5]; % Matriz con las cordenadas para formar cara frontal del cubo en x
CCx0 = [-1*CCx1(1,:);CCx1(2:3,:)];  % Matriz con las cordenadas para formar cara posterior del cubo en x

CCy1 = [-0.5  -0.5  0.5   0.5  -0.5;
        0.5   0.5  0.5   0.5   0.5;
       -0.5   0.5  0.5  -0.5  -0.5];% Matriz con las cordenadas para formar cara frontal del cubo en y
CCy0 = [CCy1(1,:);-1*CCy1(2,:);CCy1(3,:)];% Matriz con las cordenadas para formar cara posterior del cubo en y

CCz1= [-0.5  -0.5  0.5   0.5  -0.5
      -0.5   0.5  0.5  -0.5  -0.5
       0.5   0.5  0.5   0.5   0.5];% Matriz con las cordenadas para formar cara frontal del cubo en z
CCz0 = [CCz1(1:2,:);-1*CCz1(3,:)]; % Matriz con las cordenadas para formar cara posterior del cubo en z

j2= ti/300;
for j = ti:100:tf
    
    
    vM = M(j,1:3)./norm(M(j,1:3));
    vA = [-1*A(j,1) A(j,2:3)]./norm(A(j,1:3));
    
    D = vA./norm(vA);
    E = cross(D,vM);
    E = E./norm(E);
    N = cross(E,D);
    N = N./norm(N);
    MCD =[E',N',D'];
     for k=1:5   % Rotación de los vertices de las caras 
            CCx(:,k) =MCD*CCx1(:,k);
            CCX(:,k) =MCD*CCx0(:,k);
            CCy(:,k) =MCD*CCy1(:,k);
            CCY(:,k) =MCD*CCy0(:,k);
            CCz(:,k) =MCD*CCz1(:,k);
            CCZ(:,k) =MCD*CCz0(:,k);
     end

cla(app.UIAxes)
fill3(app.UIAxes,CCx(1,:),CCx(2,:),CCx(3,:),'r',CCX(1,:),CCX(2,:),CCX(3,:),[0.5 0 0]); % Reprentacion de las caras en el eje x
hold(app.UIAxes,'on')
axis(app.UIAxes, 'tight');
app.UIAxes.XLim = [-1.2 1.2];
app.UIAxes.YLim = [-1.2 1.2];
app.UIAxes.ZLim = [-1.2 1.2];
app.UIAxes.XLabel.String = 'Eje x';
app.UIAxes.YLabel.String = 'Eje y';
app.UIAxes.ZLabel.String = 'Eje z';
app.UIAxes.View = [-156,10];
fill3(app.UIAxes,CCy(1,:),CCy(2,:),CCy(3,:),'g',CCY(1,:),CCY(2,:),CCY(3,:),[0 0.5 0]); % Reprentacion de las caras en el eje y
fill3(app.UIAxes,CCz(1,:),CCz(2,:),CCz(3,:),'c',CCZ(1,:),CCZ(2,:),CCZ(3,:),[0 0 0.5]);

if rem(j,300)==0
    cla(app.UIAxes2)
    plot3(app.UIAxes2,Longitude(ti2:j2),Latitude(ti2:j2),Altitud(ti2:j2),'b','LineWidth',3);
    hold(app.UIAxes2,'on')
    plot3(app.UIAxes2,Longitude(j2),Latitude(j2),Altitud(j2),'o', 'MarkerSize',15,'MarkerFaceColor','r')
    axis(app.UIAxes2, 'tight');
    app.UIAxes2.View = [-156,10];
    app.UIAxes2.XLabel.String = 'Longitud (º)';
    app.UIAxes2.YLabel.String = 'Latitud (º)';
    app.UIAxes2.ZLabel.String = 'Altitud (m)';
    j2=j2+1;
end

%================================ Labels
    app.HoraNMEditField.Value=datestr(tLNMDATOSv1(j),'HH:MM:SS.FFF');

    if j>1 & j<129900
        etp = 'Preparación';
    elseif j>=129900 & j<217800
        etp = 'Ascenso';
    elseif j>=217800 & j<270900
        etp = 'Vuelo de Servicio';
    elseif j>=270900 & j<299400
        etp = 'Descenso';
    else
        etp = 'Aterrizaje';
    end
    app.EtapaEditField.Value = etp;
    
    app.LongitudEditField.Value = num2str(Longitude(j2));
    app.LatitudEditField.Value = num2str(Latitude(j2));
    app.AltitudEditField.Value = num2str(Altitud(j2));
    
pause(0.1)

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_Instituto_Politécnico_Nacional.png';
            app.HoraNMLabel = uilabel(app.UIFigure);
            app.HoraNMLabel.Text = {'Hora  (NM) :'; ''};
            app.HoraNMEditField = uieditfield(app.UIFigure, 'text');
            app.LongitudLabel = uilabel(app.UIFigure);
            app.LongitudLabel.Text = 'Longitud :';
            app.LongitudEditField = uieditfield(app.UIFigure, 'text');
            app.LatitudLabel = uilabel(app.UIFigure);
            app.LatitudLabel.Text = {'Latitud :'; ''};
            app.LatitudEditField = uieditfield(app.UIFigure, 'text');
            app.AltitudLabel = uilabel(app.UIFigure);
            app.AltitudLabel.Text = {'Altitud :'; ''};
            app.AltitudEditField = uieditfield(app.UIFigure, 'text');
            app.EtapaLabel = uilabel(app.UIFigure);
            app.EtapaLabel.Text = 'Etapa :';
            app.EtapaEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'º'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'º'; ''};
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.OrientaciondegrficasLabel = uilabel(app.UIFigure);
            app.OrientaciondegrficasLabel.Text = {'Orientacion de '; '       gráficas'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Screenshot 2020-06-15 at 19.11.00.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orientación')
            xlabel(app.UIAxes, 'Eje x')
            ylabel(app.UIAxes, 'Eje y')
            app.UIAxes.XLim = [-1.5 1.5];
            app.UIAxes.YLim = [-1.5 1.5];
            app.UIAxes.ZLim = [-1.5 1.5];
            app.UIAxes.ALim = [-1.5 1.5];
            app.UIAxes.XTick = [-3 0 3];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-3 0 3];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [-3 0 3];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trayectoria')
            xlabel(app.UIAxes2, 'Longitud')
            ylabel(app.UIAxes2, 'Latitud')
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActitudTrayectoria

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end