classdef ComparativaActitud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        HoraNMLabel            matlab.ui.control.Label
        HoraNMEditField        matlab.ui.control.EditField
        EtapaLabel             matlab.ui.control.Label
        EtapaEditField         matlab.ui.control.EditField
        Image2                 matlab.ui.control.Image
        MetodoDropDown_2Label  matlab.ui.control.Label
        MetodoDropDown_2       matlab.ui.control.DropDown
        MetodoDropDownLabel    matlab.ui.control.Label
        MetodoDropDown         matlab.ui.control.DropDown
        MagIMUSliderLabel      matlab.ui.control.Label
        MagIMUSlider           matlab.ui.control.Slider
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
load("Datosv1.mat")

ti = 100000;
tf = 306600; % 129900, 217800, 270900, 299400,306300, 320400;

ti2 = ti/300;

CCx1 = [0.5   0.5  0.5   0.5   0.5;
      -0.5  -0.5  0.5   0.5  -0.5;
      -0.5   0.5  0.5  -0.5  -0.5]; % Matriz con las cordenadas para formar cara frontal del cubo en x
CCx0 = [-1*CCx1(1,:);CCx1(2:3,:)];  % Matriz con las cordenadas para formar cara posterior del cubo en x

CCy1 = [-0.5  -0.5  0.5   0.5  -0.5;
        0.5   0.5  0.5   0.5   0.5;
       -0.5   0.5  0.5  -0.5  -0.5];% Matriz con las cordenadas para formar cara frontal del cubo en y
CCy0 = [CCy1(1,:);-1*CCy1(2,:);CCy1(3,:)];% Matriz con las cordenadas para formar cara posterior del cubo en y

CCz1= [-0.5  -0.5  0.5   0.5  -0.5
      -0.5   0.5  0.5  -0.5  -0.5
       0.5   0.5  0.5   0.5   0.5];% Matriz con las cordenadas para formar cara frontal del cubo en z
CCz0 = [CCz1(1:2,:);-1*CCz1(3,:)]; % Matriz con las cordenadas para formar cara posterior del cubo en z

j2= ti/300;
for j = ti:100:tf


    DD1 = app.MetodoDropDown_2.Value;
    DD2 = app.MetodoDropDown.Value;
    
    vM1 = B(j,1:3)./norm(B(j,1:3));
    vM = M(j,1:3)./norm(M(j,1:3));
    vA = A(j,1:3)./norm(A(j,1:3));
        
    D = vA;
    E = cross(D,vM);
    E = E./norm(E);
    N = cross(E,D);
    N = N./norm(N);
   
    E2 = cross(D,vM1);
  %==========================q  
    v1N = [0,1,0];
    v2N = [0,0,1];
    
    v1B = E;
    v3B = E2;
    v2B = D;
    
    w1 = app.MagIMUSlider.Value;
    w3 = 1-w1;
    w2 = 1;

    
    Bq = w1*v1B'*v1N + w2*v2B'*v2N + w3*v3B'*v1N;
    S = Bq + Bq';
    Sigma = trace(Bq);
    Z = [Bq(2,3)-Bq(3,2),Bq(3,1)-Bq(1,3),Bq(1,2)-Bq(2,1)];
    K = [Sigma,Z;
        Z',S-Sigma*eye(3)];
    if DD1=='q'| DD2=='q'
%         [V,D1]=eig(K);
%         [mx,px] = max(diag(D1));
        q =compact(qfm(j));
        C = [(q(1)^2+q(2)^2-q(3)^2-q(4)^2) 2*(q(2)*q(3)-q(1)*q(4)) 2*(q(1)*q(3)+q(2)*q(4));
        2*(q(2)*q(3)+q(1)*q(4)) (q(1)^2-q(2)^2+q(3)^2-q(4)^2) 2*(q(3)*q(4)-q(1)*q(2));
        2*(q(2)*q(4)-q(1)*q(3)) 2*(q(1)*q(2)+q(3)*q(4)) (q(1)^2-q(2)^2-q(3)^2+q(4)^2)];
        C=C';
    end
    %========================QUEST
    if DD1=='QUEST'| DD2=='QUEST'
        syms s
        Lambda = w1+w2+w3;
        q = inv((Lambda+Sigma)*eye(3)-S)*Z';
        Q = 1/sqrt(1+q'*q)*[1;q];
        q=Q;
        C1 = [(q(1)^2+q(2)^2-q(3)^2-q(4)^2) 2*(q(2)*q(3)-q(1)*q(4)) 2*(q(1)*q(3)+q(2)*q(4));
        2*(q(2)*q(3)+q(1)*q(4)) (q(1)^2-q(2)^2+q(3)^2-q(4)^2) 2*(q(3)*q(4)-q(1)*q(2));
        2*(q(2)*q(4)-q(1)*q(3)) 2*(q(1)*q(2)+q(3)*q(4)) (q(1)^2-q(2)^2-q(3)^2+q(4)^2)];
        C1=C1';
    end
    
    if DD1 =='TRIAD'
         MCD =[N',E',D'];
    elseif DD1=='q'
        MCD = C;
    else 
        MCD =C1;
    end
    
    if DD2 =='TRIAD'
         MCD2 =[N',E',D'];
    elseif DD2=='q'
        MCD2 = C;
    else 
        MCD2 =C1;
    end

     for k=1:5   % Rotación de los vertices de las caras 
            CCx(:,k) =MCD*CCx1(:,k);
            CCX(:,k) =MCD*CCx0(:,k);
            CCy(:,k) =MCD*CCy1(:,k);
            CCY(:,k) =MCD*CCy0(:,k);
            CCz(:,k) =MCD*CCz1(:,k);
            CCZ(:,k) =MCD*CCz0(:,k);
            
            CCx2(:,k) =MCD2*CCx1(:,k);
            CCX2(:,k) =MCD2*CCx0(:,k);
            CCy2(:,k) =MCD2*CCy1(:,k);
            CCY2(:,k) =MCD2*CCy0(:,k);
            CCz2(:,k) =MCD2*CCz1(:,k);
            CCZ2(:,k) =MCD2*CCz0(:,k);
     end

     
cla(app.UIAxes)
fill3(app.UIAxes,CCx(1,:),CCx(2,:),CCx(3,:),'r',CCX(1,:),CCX(2,:),CCX(3,:),[0.5 0 0]); % Reprentacion de las caras en el eje x
hold(app.UIAxes,'on')
axis(app.UIAxes, 'tight');
app.UIAxes.XLim = [-1.2 1.2];
app.UIAxes.YLim = [-1.2 1.2];
app.UIAxes.ZLim = [-1.2 1.2];
app.UIAxes.XLabel.String = 'Eje x';
app.UIAxes.YLabel.String = 'Eje y';
app.UIAxes.ZLabel.String = 'Eje z';
app.UIAxes.View = [-156,10];
fill3(app.UIAxes,CCy(1,:),CCy(2,:),CCy(3,:),'g',CCY(1,:),CCY(2,:),CCY(3,:),[0 0.5 0]); % Reprentacion de las caras en el eje y
fill3(app.UIAxes,CCz(1,:),CCz(2,:),CCz(3,:),'c',CCZ(1,:),CCZ(2,:),CCZ(3,:),[0 0 0.5]);

cla(app.UIAxes2)
fill3(app.UIAxes2,CCx2(1,:),CCx2(2,:),CCx2(3,:),'r',CCX2(1,:),CCX2(2,:),CCX2(3,:),[0.5 0 0]); % Reprentacion de las caras en el eje x
hold(app.UIAxes2,'on')
axis(app.UIAxes2, 'tight');
app.UIAxes2.XLim = [-1.2 1.2];
app.UIAxes2.YLim = [-1.2 1.2];
app.UIAxes2.ZLim = [-1.2 1.2];
app.UIAxes2.XLabel.String = 'Eje x';
app.UIAxes2.YLabel.String = 'Eje y';
app.UIAxes2.ZLabel.String = 'Eje z';
app.UIAxes2.View = [-156,10];
fill3(app.UIAxes2,CCy2(1,:),CCy2(2,:),CCy2(3,:),'g',CCY2(1,:),CCY2(2,:),CCY2(3,:),[0 0.5 0]); % Reprentacion de las caras en el eje y
fill3(app.UIAxes2,CCz2(1,:),CCz2(2,:),CCz2(3,:),'c',CCZ2(1,:),CCZ2(2,:),CCZ2(3,:),[0 0 0.5]);

%================================ Labels
    app.HoraNMEditField.Value=datestr(tLNMDATOSv1(j),'HH:MM:SS.FFF');

    if j>1 & j<129900
        etp = 'Preparación';
    elseif j>=129900 & j<217800
        etp = 'Ascenso';
    elseif j>=217800 & j<270900
        etp = 'Vuelo de Servicio';
    elseif j>=270900 & j<299400
        etp = 'Descenso';
    else
        etp = 'Aterrizaje';
    end
    app.EtapaEditField.Value = etp; 
pause(0.1)

end

        end

        % Callback function
        function iButtonPushed(app, event)
            app.EditField.Value = 5;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_Instituto_Politécnico_Nacional.png';
            app.HoraNMLabel = uilabel(app.UIFigure);
            app.HoraNMLabel.Text = {'Hora  (NM) :'; ''};
            app.HoraNMEditField = uieditfield(app.UIFigure, 'text');
            app.EtapaLabel = uilabel(app.UIFigure);
            app.EtapaLabel.Text = 'Etapa :';
            app.EtapaEditField = uieditfield(app.UIFigure, 'text');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Screenshot 2020-06-15 at 19.11.00.png';
            app.MetodoDropDown_2Label = uilabel(app.UIFigure);
            app.MetodoDropDown_2Label.Text = 'Metodo :';
            app.MetodoDropDown_2 = uidropdown(app.UIFigure);
            app.MetodoDropDown_2.Items = {'TRIAD', 'q', 'QUEST', ''};
            app.MetodoDropDown_2.Value = 'TRIAD';
            app.MetodoDropDownLabel = uilabel(app.UIFigure);
            app.MetodoDropDownLabel.Text = 'Metodo :';
            app.MetodoDropDown = uidropdown(app.UIFigure);
            app.MetodoDropDown.Items = {'TRIAD', 'q', 'QUEST', ''};
            app.MetodoDropDown.Value = 'q';
            app.MagIMUSliderLabel = uilabel(app.UIFigure);
            app.MagIMUSliderLabel.Text = 'Mag IMU';
            app.MagIMUSlider = uislider(app.UIFigure);
            app.MagIMUSlider.Limits = [0 1];
            app.MagIMUSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orientación')
            xlabel(app.UIAxes, 'Eje x')
            ylabel(app.UIAxes, 'Eje y')
            app.UIAxes.XLim = [-1.5 1.5];
            app.UIAxes.YLim = [-1.5 1.5];
            app.UIAxes.ZLim = [-1.5 1.5];
            app.UIAxes.ALim = [-1.5 1.5];
            app.UIAxes.XTick = [-3 0 3];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-3 0 3];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [-3 0 3];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Orientacón')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComparativaActitud

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end